classdef stim_threshold_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ExpControlPanel          matlab.ui.container.Panel
        StopStimButton           matlab.ui.control.Button
        StartStimButton          matlab.ui.control.Button
        CatchTrialButton         matlab.ui.control.StateButton
        EnableBeepButton         matlab.ui.control.StateButton
        StimTimesEditFieldLabel  matlab.ui.control.Label
        StimTimeEditField        matlab.ui.control.NumericEditField
        SNPParamsPanel           matlab.ui.container.Panel
        SNPParamsTable           matlab.ui.control.Table
        snp_CH16_active          matlab.ui.control.CheckBox
        snp_CH15_active          matlab.ui.control.CheckBox
        snp_CH14_active          matlab.ui.control.CheckBox
        snp_CH13_active          matlab.ui.control.CheckBox
        snp_CH12_active          matlab.ui.control.CheckBox
        snp_CH11_active          matlab.ui.control.CheckBox
        snp_CH10_active          matlab.ui.control.CheckBox
        snp_CH09_active          matlab.ui.control.CheckBox
        snp_CH08_active          matlab.ui.control.CheckBox
        snp_CH07_active          matlab.ui.control.CheckBox
        snp_CH06_active          matlab.ui.control.CheckBox
        snp_CH05_active          matlab.ui.control.CheckBox
        snp_CH04_active          matlab.ui.control.CheckBox
        snp_CH03_active          matlab.ui.control.CheckBox
        snp_CH02_active          matlab.ui.control.CheckBox
        snp_CH01_active          matlab.ui.control.CheckBox
        PWEditFieldLabel         matlab.ui.control.Label
        PAEditFieldLabel         matlab.ui.control.Label
        CHDropDownLabel          matlab.ui.control.Label
        CHDropDown               matlab.ui.control.DropDown
        PWEditField              matlab.ui.control.NumericEditField
        PAEditField              matlab.ui.control.NumericEditField
        IPIEditField             matlab.ui.control.NumericEditField
        IPIEditFieldLabel        matlab.ui.control.Label
        ConfirmButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        LastIndexSaved = '';
        TimerObject = timer( 'ExecutionMode', 'fixedRate', 'Period', 0.1, 'TasksToExecute', inf, 'BusyMode', 'drop' );

        StimParams; % structure array containing stim parameters for next block
        TrialParams = struct( 'Delay', [], 'Duration', [], 'Trial', [], 'Index', '');
        
        SummaryApp;
        WacomApp;
    end
    
    properties (Access = public)
        MouseObject;
        SaveData;
        SaveFilePath = [];
        SelectedSubjectProps;
        
        TargetObject = slrealtime('TargetPC1');
        TargetModel  = 'UECU_Speedgoat_threshold';%JDW edit to try and fix the system getting stuck

    end
    
    methods (Access = private)


        function s = get_empty_stim_params(app)
            s = struct('CH',0,'Active', 0,'PW',0,'PA',0,'IPI',0,'K1',0,'QD',0);
        end
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%% TARGET INTERACTION FUNCTIONS %%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function target_load_model(app)
        % TARGET_LOAD_MODEL(app)
        % 
        % Loads the target model specified in the app onto the target
        % machine (Speedgoat). Waits for model to load onto target.
   
          fprintf('Loading %s to TargetPC1 ...\n', app.TargetModel);
          app.TargetObject.load(app.TargetModel); % load model onto target
          disp('Load onto target complete?')
          %waitfor(app.TargetObject.ModelStatus, 'State', 'LOADED'); % wait for model to load on target (this line possibly does not work) %JDW editing out the line that may not work
          fprintf('%s loaded to TargetPC1!\n', app.TargetModel)
        end
        
        function target_update_param(app, block_name, param_name, value)
        % TARGET_UPDATE_PARAM(app, block_name, param_name, value)
        % 
        % Sets parameter value in specified Simulink block for target
        % model (specified under app.TargetModel).
        
          block_path = [app.TargetModel '/' block_name]; % get path name of Simulink block
          curr_val = app.TargetObject.getparam(block_path, param_name); % get current value of block parameter
          value = cast([value, zeros(1,length(curr_val)-length(value))], class(curr_val)); % pad and cast new value to current value data type
          app.TargetObject.setparam(block_path, param_name, value); % set block parameter with new values
        end
        
        function flag = target_set_block_params(app)
        % TARGET_SET_BLOCK_PARAMS(app, block_params)
        % 
        % Sets parameters of target model based on the values contained in 
        % the block_params structure.
            
            % Initialize success flag
            flag = false;
        
            % If model is not already loaded onto target, load model onto target
            if ~strcmp(app.TargetObject.ModelStatus.State, 'LOADED')
                target_load_model(app);
            end
            
            ch_in  = zeros(1,24);
            pw_in  = zeros(1,24);
            pa_in  = zeros(1,24);
            ipi_in = zeros(1,24);
            for i = 1:16
                ch_in(i)  = app.StimParams(i).Active;
                pw_in(i)  = app.StimParams(i).PW;
                pa_in(i)  = app.StimParams(i).PA;
                ipi_in(i) = app.StimParams(i).IPI;
            end
            
            if length(unique(ipi_in(ipi_in ~= 0))) > 1
                errordlg('Multiple different IPIs currently not supported!', 'Stim Parameter Error');
                %ClearMemoryButtonPushed(app);
                return;
            end
            
            target_update_param(app, 'trial_delay_in', 'Value', app.TrialParams.Delay);
            target_update_param(app, 'trial_stop_in',  'Value', app.TrialParams.Duration);
            target_update_param(app, 'Enable Beep',    'Value', app.EnableBeepButton.Value);
            
            target_update_param(app, 'snp_CH_in',  'Value', ch_in);
            target_update_param(app, 'snp_PW_in',  'Value', pw_in);
            target_update_param(app, 'snp_PA_in',  'Value', pa_in);
            target_update_param(app, 'snp_IPI_in', 'Value', max(ipi_in));
            
            % Set success flag to true
            flag = true;
        end
        
        function [time, data] = export_target_data(app)
        % [time, data] = EXPORT_TARGET_DATA(app)
        % 
        % Gets data stored on the target machine. Assumes that the target
        % machine only has 1 log. Assumes that the model only contains a
        % single FileLog block.
        
            % Get the FileLog object from the target
            tg_file_log = app.TargetObject.FileLog;
            
            % Check if any data is available on the target
            if ~tg_file_log.DataAvailable 
                time = [];
                data = [];
                warndlg('No data available on target!', 'Target Check');
                return;
            end
            
            % Export data from target to Simulink Data Inspector to workspace as DataSet object 
            app.TargetObject.FileLog.import(1); % import data to SDI
            runID = app.TargetObject.SDIRunId; % get run ID for target
            tg_data_set = Simulink.sdi.exportRun(runID); % export latest run from SDI
            
            % Set outputs (get time and data vectors from DataSet object)
            tg_data_output_element = tg_data_set.getElement('output'); % signal must be named 'output'
            time = tg_data_output_element.Values.Time; % time vector
            data = tg_data_output_element.Values.Data; % data vector
        end

        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%% TIMER FUNCTIONS %%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function user_timer_start(app, obj, event)
            
            % Disable the drawing GUI
            %app.DrawingGUIButton.Value = false;
            app.WacomApp.disable_draw;
            app.WacomApp.clear_wacom;
            
            % Refresh scopes and start target simulation
            app.TargetObject.start('StopTime', inf , 'ReloadOnStop', true, 'AutoImportFileLog', false, 'ExportToBaseWorkspace', false);
        end
        
        function user_timer_update(app, obj, event)
            
            % If target is not running, stop the timer
            if ~app.TargetObject.isRunning
                stop(app.TimerObject);
                return;
            end
        end
        
        function user_timer_stop(app, obj, event)
            
            % Stop target and clear instrument data
            if app.TargetObject.isRunning
                app.TargetObject.stop;
            end
            
            % Enable the drawing GUI
            %app.DrawingGUIButton.Value = true;
            app.WacomApp.enable_draw;
            
            app.CatchTrialButton.Value = false;
            
            app.SummaryApp.add_trial(app.SaveData);
            
            % Extra button options to ensure proper experimentation
            app.SaveButton.FontColor = 'r';
        end
        
        function user_timer_error(app, obj, event)
            stop(app.TimerObject);
        end
        
    end
    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Initially disable the app while model loads to target
            app.SNPParamsPanel.Enable  = 'off';
            app.ExpControlPanel.Enable = 'off';
            %app.ResponsePanel.Enable   = 'off';
            %app.SavePanel.Enable       = 'off';
            
            % Ask to select subject
            init_app = initialize_subject(app);
            while isvalid(init_app)
                pause(0.1);
            end
            
            % Update cuff names
            %app.Cuff1Button.Text = app.SelectedSubjectProps.CuffNames{1};
            %app.Cuff2Button.Text = app.SelectedSubjectProps.CuffNames{2};
            %app.Cuff3Button.Text = app.SelectedSubjectProps.CuffNames{3};
            
            % Create robot to move mouse
            import java.awt.Robot;
            app.MouseObject = Robot;
            disp('Robot Built')% JDW addition on 03/22/2022
            
            % Set the timer callback functions
            app.TimerObject.StartFcn = {@app.user_timer_start};
            app.TimerObject.TimerFcn = {@app.user_timer_update};
            app.TimerObject.StopFcn  = {@app.user_timer_stop};
            app.TimerObject.ErrorFcn = {@app.user_timer_error};
            disp('Timer function Built')% JDW addition on 03/22/2022
            
            % Initialize the SNP parameter table
            %ResetTableButtonPushed(app);
            %ClearMemoryButtonPushed(app);
            disp('SNP Parameter Table Built')% JDW addition on 03/22/2022
            
            % Initialize sub apps
            app.SummaryApp = n_trials_summary(app);
            app.WacomApp   = wacom_draw(app, app.SelectedSubjectProps.AmputatedSide);
            disp('Sub apps initialized')
            
            % Build and load the model onto the target
            slbuild(app.TargetModel);
            disp('slbuild complete')
            target_load_model(app);
            disp('target load complete')
            
            % Enable the app
            app.SNPParamsPanel.Enable  = 'on';
            app.ExpControlPanel.Enable = 'on';
            %app.ResponsePanel.Enable   = 'on';
            %app.SavePanel.Enable       = 'on';
            disp('Panels enabled')
        end

        % Button pushed function: StartStimButton
        function StartStimButtonPushed(app, event)
            
            % Save delay and duration parameters
            %app.TrialParams.Delay    = app.DelayEditField.Value;
            app.TrialParams.Duration = app.StimTimeEditField.Value;


            % If catch trial, set all values to 0
            ch_sel = zeros(1,16);
            if app.CatchTrialButton.Value
                %ClearMemoryButtonPushed(app);
                
            % If not catch trial, save stim parameters
            else
                for i = 1:16
                    ch_sel_i  = app.(sprintf('snp_CH%02d_active',i)).Value;
                    ch_sel(i) = ch_sel_i;
                    app.StimParams(i).CH     = i;
                    app.StimParams(i).Active = ch_sel_i;
                    
                    % If channel is selected for stim (check box is clicked)
                    if ch_sel_i
                        
                        % Save stim parameters to StimParams 
                        app.StimParams(i).PW  = str2double(app.SNPParamsTable.Data(i,3));
                        app.StimParams(i).PA  = str2double(app.SNPParamsTable.Data(i,4));
                        app.StimParams(i).IPI = str2double(app.SNPParamsTable.Data(i,5));

                        
                         % if there is invalid param or cell empty, there will be a NaN
                        if any(isnan(struct2array(app.StimParams(i))))
                            warndlg('Invalid stim parameter detected! Please enter valid stim parameters.', 'Stim Parameter Error');
                            %ClearMemoryButtonPushed(app); % reset StimParams to 0
                            return;
                        end
                        
                    % If channel is not selected for stim (check box is empty)
                    else
                        app.StimParams(i).PW  = 0;
                        app.StimParams(i).PA  = 0;
                        app.StimParams(i).IPI = 0;
                        app.StimParams(i).K1  = 0;
                        app.StimParams(i).QD  = 0;
                    end
                end
            end
            
            
            if app.CatchTrialButton.Value || all(~ch_sel) % if not outputting any stim, save line with CH = 0
                app.SaveData = get_empty_stim_params(app);
            else
                app.SaveData = app.StimParams(find(ch_sel));
            end
            for i = 1 : length(app.SaveData)
                app.SaveData(i).Index    = app.TrialParams.Index;
                app.SaveData(i).Date     = datestr(now, 'yyyy-mm-dd');
                app.SaveData(i).Time     = datestr(now, 'HH:MM:SS');
                app.SaveData(i).Duration = app.TrialParams.Duration;
                %app.SaveData(i).Cuff     = cuff_sel;
                %app.SaveData(i).Response.Location  = [];
                %app.SaveData(i).Response.Comments  = [];
                %app.SaveData(i).Response.Intensity = [];
            end
            
            % Update parameters on target
            updated = target_set_block_params(app);
            
            % Start the model
            if updated 
                start(app.TimerObject); % model is started when timer is started
            end
        end

        % Button pushed function: StopStimButton
        function StopStimButtonPushed(app, event)
            stop(app.TimerObject);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            CH  = str2double(app.CHDropDown.Value);
            PW  = app.PWEditField.Value;
            PA  = app.PAEditField.Value;
            IPI = app.IPIEditField.Value;
            
            %[~, PW] = check_PW_limits(app, PW);
            %[~, PA] = check_PA_limits(app, PA);
            %[K1, QD] = safe_charge(app, PA, PW);
            
            app.SNPParamsTable.Data{CH,3} = sprintf('%0.3f',PW);
            app.SNPParamsTable.Data{CH,4} = sprintf('%0.3f',PA);
            app.SNPParamsTable.Data{CH,5} = sprintf('%0.3f',IPI);
            %app.SNPParamsTable.Data{CH,6} = sprintf('%0.3f',K1);
            %app.SNPParamsTable.Data{CH,7} = sprintf('%0.3f',QD);
            
            app.ConfirmButton.FontColor = 'k';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            % Stop and delete all remaining timers
            timers = timerfindall;
            if ~isempty(timers)
                delete(timers);
            end
            
            try
                delete(app.SummaryApp);
            end
            try
                delete(app.WacomApp);                
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExpControlPanel = uipanel(app.UIFigure);
            app.StopStimButton = uibutton(app.ExpControlPanel, 'push');
            app.StopStimButton.ButtonPushedFcn = createCallbackFcn(app, @StopStimButtonPushed, true);
            app.StopStimButton.Text = 'Stop Stim';
            app.StartStimButton = uibutton(app.ExpControlPanel, 'push');
            app.StartStimButton.ButtonPushedFcn = createCallbackFcn(app, @StartStimButtonPushed, true);
            app.StartStimButton.Text = 'Start Stim';
            app.CatchTrialButton = uibutton(app.ExpControlPanel, 'state');
            app.CatchTrialButton.Text = 'Catch Trial';
            app.EnableBeepButton = uibutton(app.ExpControlPanel, 'state');
            app.EnableBeepButton.Text = 'Enable Beep';
            app.EnableBeepButton.Value = true;
            app.StimTimesEditFieldLabel = uilabel(app.ExpControlPanel);
            app.StimTimesEditFieldLabel.Text = 'Stim Time (s)';
            app.StimTimeEditField = uieditfield(app.ExpControlPanel, 'numeric');
            app.SNPParamsPanel = uipanel(app.UIFigure);
            app.SNPParamsPanel.Title = 'Sensory Neuroprosthesis Parameters';
            app.SNPParamsTable = uitable(app.SNPParamsPanel);
            app.SNPParamsTable.ColumnName = {'CH'; 'On/Off'; 'PW (us)'; 'PA (mA)'; 'IPI (ms)'; 'K1<1.5-1.7'; 'QD<50 (uC/cm2)'};
            app.SNPParamsTable.RowName = {};
            app.SNPParamsTable.ColumnEditable = [false false true true true false false];
            app.snp_CH16_active = uicheckbox(app.SNPParamsPanel);
            app.snp_CH16_active.Tag = '16';
            app.snp_CH16_active.Text = '';
            app.snp_CH15_active = uicheckbox(app.SNPParamsPanel);
            app.snp_CH15_active.Tag = '15';
            app.snp_CH15_active.Text = '';
            app.snp_CH14_active = uicheckbox(app.SNPParamsPanel);
            app.snp_CH14_active.Tag = '14';
            app.snp_CH14_active.Text = '';
            app.snp_CH13_active = uicheckbox(app.SNPParamsPanel);
            app.snp_CH13_active.Tag = '13';
            app.snp_CH13_active.Text = '';
            app.snp_CH12_active = uicheckbox(app.SNPParamsPanel);
            app.snp_CH12_active.Tag = '12';
            app.snp_CH12_active.Text = '';
            app.snp_CH11_active = uicheckbox(app.SNPParamsPanel);
            app.snp_CH11_active.Tag = '11';
            app.snp_CH11_active.Text = '';
            app.snp_CH10_active = uicheckbox(app.SNPParamsPanel);
            app.snp_CH10_active.Tag = '10';
            app.snp_CH10_active.Text = '';
            app.snp_CH09_active = uicheckbox(app.SNPParamsPanel);
            app.snp_CH09_active.Tag = '9';
            app.snp_CH09_active.Text = '';
            app.snp_CH08_active = uicheckbox(app.SNPParamsPanel);
            app.snp_CH08_active.Tag = '8';
            app.snp_CH08_active.Text = '';
            app.snp_CH07_active = uicheckbox(app.SNPParamsPanel);
            app.snp_CH07_active.Tag = '7';
            app.snp_CH07_active.Text = '';
            app.snp_CH06_active = uicheckbox(app.SNPParamsPanel);
            app.snp_CH06_active.Tag = '6';
            app.snp_CH06_active.Text = '';
            app.snp_CH05_active = uicheckbox(app.SNPParamsPanel);
            app.snp_CH05_active.Tag = '5';
            app.snp_CH05_active.Text = '';
            app.snp_CH04_active = uicheckbox(app.SNPParamsPanel);
            app.snp_CH04_active.Tag = '4';
            app.snp_CH04_active.Text = '';
            app.snp_CH03_active = uicheckbox(app.SNPParamsPanel);
            app.snp_CH03_active.Tag = '3';
            app.snp_CH03_active.Text = '';
            app.snp_CH02_active = uicheckbox(app.SNPParamsPanel);
            app.snp_CH02_active.Tag = '2';
            app.snp_CH02_active.Text = '';
            app.snp_CH01_active = uicheckbox(app.SNPParamsPanel);
            app.snp_CH01_active.Tag = '1';
            app.snp_CH01_active.Text = '';
            app.PWEditFieldLabel = uilabel(app.SNPParamsPanel);
            app.PWEditFieldLabel.Text = 'PW';
            app.PAEditFieldLabel = uilabel(app.SNPParamsPanel);
            app.PAEditFieldLabel.Text = 'PA';
            app.CHDropDownLabel = uilabel(app.SNPParamsPanel);
            app.CHDropDownLabel.Text = 'CH';
            app.CHDropDown = uidropdown(app.SNPParamsPanel);
            app.CHDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.CHDropDown.Value = '1';
            app.PWEditField = uieditfield(app.SNPParamsPanel, 'numeric');
            app.PAEditField = uieditfield(app.SNPParamsPanel, 'numeric');
            app.IPIEditField = uieditfield(app.SNPParamsPanel, 'numeric');
            app.IPIEditFieldLabel = uilabel(app.SNPParamsPanel);
            app.IPIEditFieldLabel.Text = 'IPI';
            app.ConfirmButton = uibutton(app.SNPParamsPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stim_threshold_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end